classdef Covid_Data_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure  matlab.ui.Figure
        CountryListBoxLabel    matlab.ui.control.Label
        CountryListBox         matlab.ui.control.ListBox
        DatatoPlotButtonGroup  matlab.ui.container.ButtonGroup
        CasesButton            matlab.ui.control.RadioButton
        DeathsButton           matlab.ui.control.RadioButton
        BothButton             matlab.ui.control.RadioButton
        OptionButtonGroup      matlab.ui.container.ButtonGroup
        CumulativeButton       matlab.ui.control.RadioButton
        DailyButton            matlab.ui.control.RadioButton
        StateorRegionLabel     matlab.ui.control.Label
        StateorRegionListBox   matlab.ui.control.ListBox
        AveragedofdaysLabel    matlab.ui.control.Label
        AveragedofdaysSlider   matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        global_data
        glob_cases
        glob_deaths
        only_cases
        only_deaths
        both
        for_cumulative
        d1
        d2
        mov_average_window
        is_country_val_changed
        is_state_val_changed
        country_val
        state_val
        country_deaths
        country_cases
        country_index
        y_case
        y_death
        axis_title
    end
    
    methods (Access = private)
        function add_countries_to_box(app,countries)
            app.glob_cases = zeros(1,375);
            app.glob_deaths = zeros(1,375);
            x = string(zeros(1,length(countries)+1));
            x(1) = "Global";
            for i=1:length(countries)
                %fprintf("%s\n",countries{i}.name)
                x(i+1) = countries{i}.name;
                app.glob_cases = app.glob_cases + countries{i}.cases;
                app.glob_deaths = app.glob_deaths + countries{i}.deaths;
            end
            %fprintf("%d --- %d\n",length(app.glob_cases),length(app.glob_deaths))
            app.CountryListBox.Items = x;
            app.CountryListBox.Value = "Global";
        end
        
        function add_state_to_box(app,val)
            countries = app.global_data.objects;
            if ~isequal(val,"Global")
                len = length(countries);
                for i=1:len
                    %fprintf("%s ---- %s\n",val,countries{i}.name);
                    if isequal(val,countries{i}.name)
                        if ~isempty(countries{i}.objects)
                            x = string(zeros(1,length(countries{i}.objects)+1));
                            x(1) = "All";
                            for j=1:length(countries{i}.objects)
                                x(j+1) = countries{i}.objects{j}.name;
                            end
                            %disp(x)
                            app.StateorRegionListBox.Items=x;
                            app.StateorRegionListBox.Value="All";
                        else
                            app.StateorRegionListBox.Items="All";
                            app.StateorRegionListBox.Value="All";
                        end
                        break
                    end
                end
            else
                app.StateorRegionListBox.Items="All";
                app.StateorRegionListBox.Value="All";
            end
        end
        
        function calculate_data(app)
            app.y_case = app.glob_cases;
            app.y_death = app.glob_deaths;
            
            if app.is_country_val_changed
                if ~isequal(app.country_val,'Global')
                    % loop for specific country
                    countries = app.global_data.objects;
                    len = length(countries);
                    
                    for i=1:len
                        %fprintf("%s ---- %s\n",app.country_val,countries{i}.name);
                        if isequal(app.country_val,countries{i}.name)
                            app.y_case = countries{i}.cases;
                            app.y_death = countries{i}.deaths;
                            app.country_index = i;
                            %fprintf("+++++ %d +++++",app.country_index);
                            break
                        end
                    end
                end
                
                app.is_country_val_changed = false;
                app.country_deaths = app.y_death;
                app.country_cases = app.y_case;
            end
            
            if app.is_state_val_changed
                if isequal(app.state_val,'All')
                    app.y_case = app.country_cases;
                    app.y_death = app.country_deaths;
                else
                    country = app.global_data.objects{app.country_index};
                    %fprintf("+++++ %s +++++",country.name);
                    state = country.objects;
                    len = length(state);
                    for i=1:len
                        %fprintf("%s ---- %s\n",app.state_val,state{i}.name);
                        if isequal(app.state_val,state{i}.name)
                            app.y_case = state{i}.cases;
                            app.y_death = state{i}.deaths;
                            break
                        end
                    end
                end
                app.is_state_val_changed = false;
            end
            app.final_plot();
        end
        
        function res = moving_average(app,data)
            a = 1;
            b = (1/app.mov_average_window)*ones(1,app.mov_average_window);
            res = filter(b,a,data);
        end
        
        function final_plot(app)
            x = app.d1:1:app.d2;
            
            y_c = app.y_case;
            y_d = app.y_death;
            
            if ~(app.for_cumulative)
                y_c = [y_c(1) diff(y_c)];
                y_d = [y_d(1) diff(y_d)];
                
                y_c(y_c<=0)=0;
                y_d(y_d<=0)=0;
            end
            
            y_c = app.moving_average(y_c);
            y_d = app.moving_average(y_d);
            %fprintf("%d -- %d -- %d \n",length(x),length(y_case),length(y_death));
            
            if app.only_cases
                yyaxis(app.UIAxes,'right');
                cla(app.UIAxes,'reset');
                bar(app.UIAxes,x,y_c);
            end
            
            if app.only_deaths
                yyaxis(app.UIAxes,'right');
                cla(app.UIAxes,'reset');
                plot(app.UIAxes,x,y_d);
            end
            
            if app.both
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,x,y_c,'b');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,x,y_d);
                
            end
            fprintf("%s",join(string(app.axis_title)));
            app.UIAxes.Title.String = join(string(app.axis_title));
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[app.d1 app.d2]);
            xlim(app.UIAxes,'manual');
            xtickformat(app.UIAxes,'dd/MM/yy');
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load Data_read_and_stored_for_directly_loading.mat global_storage;
            app.global_data = global_storage;
            
            app.d1 = datetime('01/22/20','InputFormat','MM/dd/yy');
            app.d2 = datetime('01/30/21','InputFormat','MM/dd/yy');
            
            app.axis_title = {"Cummulative","number of","cases","globally",""};
            
            app.for_cumulative = true;
            
            app.only_cases = true;
            app.only_deaths = false;
            app.both = false;
            
            app.country_val = 'Global';
            app.state_val = 'All';
            
            app.is_country_val_changed = false;
            app.is_state_val_changed = false;
            
            app.mov_average_window = 1;
            % write the code if loading of variable is not allowed
                        
            app.StateorRegionListBox.Items = "All";
            app.StateorRegionListBox.Value = "All";
            
            app.add_countries_to_box(app.global_data.objects);
            app.calculate_data();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            app.is_country_val_changed = true;
            app.country_val = value;
            
            if app.is_country_val_changed
                app.axis_title{4} = "in " + app.country_val;
            end
            
            %adding state according to the company
            app.add_state_to_box(value);
            
            app.calculate_data();          
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.both = false;
            app.only_cases = false;
            app.only_deaths = false;
            if app.BothButton.Value
                app.axis_title{3} = "cases and deaths ";
                app.both = true;
            elseif app.CasesButton.Value
                app.axis_title{3} = "cases ";
                app.only_cases = true;
            else
                app.axis_title{3} = "deaths ";
                app.only_deaths = true;
            end
            app.final_plot();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.for_cumulative = false;
            if app.CumulativeButton.Value
                app.for_cumulative = true;
                app.axis_title{1} = "Cummulative number of ";
            else
                app.axis_title{1} = "Daily number of ";
            end
            app.final_plot();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.mov_average_window = floor(event.Value);
            if (app.mov_average_window > 1)
                app.axis_title{5} = "(" + app.mov_average_window + "-day averaged)";
            else
                app.axis_title{5} = "";
            
            end
            app.final_plot();
            %fprintf("%d\n",changingValue)
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            value = app.StateorRegionListBox.Value;
            %fprintf("***** %s **** \n",value);
            app.is_state_val_changed = true;
            app.state_val = value;
            
            if app.is_state_val_changed
                if ~isequal(app.state_val,"All")
                    app.axis_title{4} = "in " + app.state_val;
                else
                    app.axis_title{4} = "in " + app.country_val;
                end
            end
            
            app.calculate_data();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure = uifigure('Visible', 'off');
            app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure.Name = 'Covid-19 Data Visualtzation (Data Source: John Hopkins University)';
            app.CountryListBoxLabel = uilabel(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.StateorRegionLabel = uilabel(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.StateorRegionLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.AveragedofdaysLabel = uilabel(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofdaysLabel.Text = 'Averaged# of days';
            app.AveragedofdaysSlider = uislider(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.MinorTicks = [2 4 6 8 10 12 14];
            app.AveragedofdaysSlider.Value = 1;
            app.UIAxes = uiaxes(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_Data_Visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DataVisualtzationDataSourceJohnHopkinsUniversityUIFigure)
        end
    end
end